classdef Synthesiser < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        FileMenu              matlab.ui.container.Menu
        ImportMenu            matlab.ui.container.Menu
        ExportMenu            matlab.ui.container.Menu
        ClearMenu             matlab.ui.container.Menu
        GridLayout            matlab.ui.container.GridLayout
        TabGroup              matlab.ui.container.TabGroup
        WaveformTab           matlab.ui.container.Tab
        WaveformContainer     matlab.ui.container.GridLayout
        WaveformAxes          matlab.ui.control.UIAxes
        SpectrogramTab        matlab.ui.container.Tab
        SpectrogramContainer  matlab.ui.container.GridLayout
        SpectrogramAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sample % The sample loaded from file for the synthesiser.
        sampleRate % The sample rate of the currently loaded audio sample.
        sampleName % The name of the file being used as a sample.
    end
    
    methods (Access = private)
        
        % Method to import an audio sample
        function [y, Fs] = importAudio(app)
            [file, path] = uigetfile(["*.mp3;*.flac;*.m4a;*.mp4;*.oga;*.ogg;*.wav", "Audio Files"]);

            if path ~= 0
                fullPath = append(path, file);
                [y, Fs] = audioread(fullPath);
                
                app.sample = y(:, 1); % Convert sample to mono to simplfy future operations
                app.sampleRate = Fs;
                app.sampleName = file;
            end
        end
        
        % Method to export the audio resulting from the synthesiser changes
        function fullPath = exportAudio(app)
            [file, path] = uiputfile(["*.flac;*.oga;*.ogg;*.wav", "Audio files"]);

            if path ~= 0
                fullPath = append(path, file);
                audiowrite(fullPath, app.sample, app.sampleRate);
            end
        end
        
        % Method to clear the current audio sample from storage
        function clearAudio(app)
            app.sample = [];
            app.sampleRate = 0;
            app.sampleName = "None";
        end


        % Method to plot the graphs in the app
        function plotGraphs(app)
            if ~isempty(app.sample)
                % Define variables used by both plots
                y = app.sample;
                Fs = app.sampleRate;
                dur = length(y)/Fs; % Calculate duration
                xLimit = [0, length(y)];

                % Plot Spectrogram
                axes = app.SpectrogramAxes;

                [S, F, T] = spectrogram(y, 100);
                imagesc(axes, [0, length(y)], [0, 1], log(abs(S)));
                set(axes, "YDir", "normal")
                xlim(axes, xLimit);
                ylim(axes, [0, 1]);
                disableDefaultInteractivity(axes);

                % Plot Waveform
                axes = app.WaveformAxes;
                plot(axes, y, "b");
                xlim(axes, xLimit);
                ylim(axes, [-1, 1]);
                disableDefaultInteractivity(axes);
            end
        end
        
        % Method to clear graphs of data
        function clearGraphs(app)
            cla(app.WaveformAxes);
            cla(app.SpectrogramAxes);
        end
    
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: ImportMenu
        function ImportMenuSelected(app, event)
            app.importAudio();
            app.plotGraphs();
        end

        % Menu selected function: ExportMenu
        function ExportMenuSelected(app, event)
            app.exportAudio();
        end

        % Menu selected function: ClearMenu
        function ClearMenuSelected(app, event)
            app.clearAudio();
            app.clearGraphs();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ImportMenu = uimenu(app.FileMenu);
            app.ImportMenu.MenuSelectedFcn = createCallbackFcn(app, @ImportMenuSelected, true);
            app.ImportMenu.Text = 'Import';
            app.ExportMenu = uimenu(app.FileMenu);
            app.ExportMenu.MenuSelectedFcn = createCallbackFcn(app, @ExportMenuSelected, true);
            app.ExportMenu.Text = 'Export';
            app.ClearMenu = uimenu(app.FileMenu);
            app.ClearMenu.MenuSelectedFcn = createCallbackFcn(app, @ClearMenuSelected, true);
            app.ClearMenu.Text = 'Clear';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.WaveformTab = uitab(app.TabGroup);
            app.WaveformTab.Title = 'Waveform';
            app.WaveformContainer = uigridlayout(app.WaveformTab);
            app.WaveformAxes = uiaxes(app.WaveformContainer);
            title(app.WaveformAxes, 'Waveform')
            xlabel(app.WaveformAxes, 'Sample')
            ylabel(app.WaveformAxes, 'Magnitude')
            app.SpectrogramTab = uitab(app.TabGroup);
            app.SpectrogramTab.Title = 'Spectrogram';
            app.SpectrogramContainer = uigridlayout(app.SpectrogramTab);
            app.SpectrogramAxes = uiaxes(app.SpectrogramContainer);
            title(app.SpectrogramAxes, 'Spectrogram')
            xlabel(app.SpectrogramAxes, 'Sample')
            ylabel(app.SpectrogramAxes, 'Normalised Frequency (log)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Synthesiser

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end